classdef RobotApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigureforSimulatingControllingQuanser2DOFRobot  matlab.ui.Figure
        HelpMenu                        matlab.ui.container.Menu
        ApplicationHelpMenu             matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        ModelNameEditField              matlab.ui.control.EditField
        ModelNameEditFieldLabel         matlab.ui.control.Label
        RobotEnvironmentPanel           matlab.ui.container.Panel
        WallStiffnessEditField          matlab.ui.control.EditField
        WallStiffnessNmmEditFieldLabel  matlab.ui.control.Label
        DisplayWallCheckBox             matlab.ui.control.CheckBox
        DataAnalysisPanel               matlab.ui.container.Panel
        ScriptNameEditField             matlab.ui.control.EditField
        ScriptNameEditFieldLabel        matlab.ui.control.Label
        RunScriptButton                 matlab.ui.control.Button
        OperationsPanel                 matlab.ui.container.Panel
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        ParameterTuningPanel            matlab.ui.container.Panel
        GraphicsRateEditField           matlab.ui.control.EditField
        GraphicsRefreshRatesLabel       matlab.ui.control.Label
        SampleTimeEditField             matlab.ui.control.EditField
        FundamentalSampleTimesLabel     matlab.ui.control.Label
        StopTimeEditField               matlab.ui.control.EditField
        StopTimesEditFieldLabel         matlab.ui.control.Label
        SpeedEditField                  matlab.ui.control.EditField
        TrajectorySpeedcirclessLabel    matlab.ui.control.Label
        ModeButtonGroup                 matlab.ui.container.ButtonGroup
        ExternalButton                  matlab.ui.control.RadioButton
        NormalButton                    matlab.ui.control.RadioButton
        RobotAxes                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        robot; % structure with drawing objects for robot visualization
        modelName; % name of Simulink model
        tuning;  % structure with model parameters to be tuned
        viewing; % structure with model events to listen in on
        eventHandle; % cell array of handles to event listeners
    end

    methods (Access = private)

        function modelLoaded = modelIsLoaded(app,modelName)
            try
                modelLoaded = ...
                    ~isempty(find_system('Type','block_diagram','Name',modelName));
            catch ME %#ok
                % Return false if the model can't be found
                modelLoaded = false;
            end
        end

        function [] = checkModelConfigurationParameters(app)
            if ~strcmp(get_param(app.modelName,'SystemTargetFile'),'quarc_win64.tlc')
                warndlg('The System Target file is not set to "quarc_win64.tlc"');
            end
            if ~strcmp(get_param(app.modelName,'SolverType'),'Fixed-step')
                warndlg('The Solver Type needs to be set to "Fixed-Step" for external mode');
            end
            if ~strcmp(get_param(app.modelName,'SolverName'),'ode1')
                warndlg('The Solver is not set to "ode1". "ode1" is recommended for external mode.');
            end
            sampletime = str2double(get_param(app.modelName,'FixedStep'));
            if sampletime < 0.001
                warndlg('The Fundamental Sampletime is too small. It should be at least 0.001 when using external mode.');
            end
        end

        function [] = addEventListener(app)
            % Add the listener(s)
            % For this example all events call into the same function
            app.eventHandle = cell(1,length(app.viewing));
            for idx = 1:length(app.viewing)
                app.eventHandle{idx} = ...
                    add_exec_event_listener(app.viewing(idx).blockName,...
                    app.viewing(idx).blockEvent, app.viewing(idx).blockFcn);
            end
        end

        function [] = removeEventListener(app)
            % delete the listener(s)
            if (isfield(app,'eventHandle'))
                for idx = 1:length(app.eventHandle)
                    if ishandle(app.eventHandle{idx})
                        delete(app.eventHandle{idx});
                    end
                end
            end
        end

        function [] = stopSimulation(app)

            removeEventListener(app);

            app.ModelNameEditField.Enable='on';
            app.ParameterTuningPanel.Enable='on';
            app.ModeButtonGroup.Enable='on';
            app.StartButton.Enable='on';
            app.StopButton.Enable='off';
        end

    end

    methods (Access = public)

        function [] = jointAngleListener(app, block, eventdata)
            Time = block.CurrentTime;
            phi = block.InputPort(1).Data;
            R1 = [cos(phi(1)) -sin(phi(1)); sin(phi(1)) cos(phi(1))];
            R2 = [cos(phi(2)) -sin(phi(2)); sin(phi(2)) cos(phi(2))];
            link1points = R1*app.robot.linkpoints;
            link2points = R2*app.robot.linkpoints;
            beltpoints = R1*app.robot.beltpoints;
            org1 = R1*[app.robot.a1;0];
            org2 = org1+R2*[app.robot.a2;0];
            X = [get(app.robot.path,'XData') org2(1)];
            Y = [get(app.robot.path,'YData') org2(2)];
            hole = app.robot.hole;
            pulley = app.robot.pulley;
            set(app.robot.link1,'XData',link1points(1,:),'YData',link1points(2,:));
            set(app.robot.joint2,'Position',[org1(1)-hole/2 org1(2)-hole/2 hole hole]);
            set(app.robot.belt,'XData',beltpoints(1,:),'YData',beltpoints(2,:));
            set(app.robot.pulley2,'Position',[org1(1)-pulley/2 org1(2)-pulley/2 pulley pulley]);
            set(app.robot.link2,'XData',link2points(1,:)+org1(1),'YData',link2points(2,:)+org1(2));
            set(app.robot.tool,'Position',[org2(1)-hole/2 org2(2)-hole/2 hole hole]);
            set(app.robot.path,'XData',X,'YData',Y);
            set(app.robot.time,'String',sprintf('%.2f',Time));
            drawnow;
            if (Time>=(str2double(app.tuning.StopTime)))
                stopSimulation(app);
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            a1 = 0.15; % link lengths
            a2 = 0.15;
            w = 0.025; % link width
            hole = 0.007; % hole diameter
            base = 0.0635; % base width
            pulley = 0.04; % pulley diameter

            linkpoints = [0;-w/2];
            for phi = -90:10:90
                pnew = [a1+w/2*cos(phi*pi/180); w/2*sin(phi*pi/180)];
                linkpoints = [linkpoints pnew];
            end
            for phi = 90:10:270
                pnew = [w/2*cos(phi*pi/180); w/2*sin(phi*pi/180)];
                linkpoints = [linkpoints pnew];
            end

            beltpoints = [0 a1 a1 0;-pulley/2 -pulley/2 pulley/2 pulley/2];
            Uxpoints = [1 10 10 8 8 4 -4 -8 -8 -10 -10 -1 -1 -3 -3 3 3 1 1]/700;
            Uypoints = [19 19 13 13 4 0 0 4 13 13 19 19 13 13 6 6 13 13 19]/700;

            app.robot.a1 = a1;
            app.robot.a2 = a2;
            app.robot.hole = hole;
            app.robot.pulley = pulley;
            app.robot.linkpoints = linkpoints;
            app.robot.beltpoints = beltpoints;

            app.robot.wall = rectangle(app.RobotAxes,'Position',[0.1125 -base 0.175 base-0.025-w/2],'FaceColor',[0.7 0.5 0.25],'Visible','off');
            app.robot.base = rectangle(app.RobotAxes,'Position',[-base -base 2*base 2*base],'Curvature',[0.1 0.1],'FaceColor','k');
            app.robot.belt = line(app.RobotAxes,'XData',beltpoints(2,:),'YData',beltpoints(1,:),'Color',[0.7 0.5 0.25]);
            app.robot.pulley1 = rectangle(app.RobotAxes,'Position',[-pulley/2 -pulley/2 pulley pulley],'Curvature',[1 1],'FaceColor',[0.5 0.5 0.5]);
            app.robot.pulley2 = rectangle(app.RobotAxes,'Position',[-pulley/2 a1-pulley/2 pulley pulley],'Curvature',[1 1],'FaceColor',[0.5 0.5 0.5]);
            app.robot.link2 = patch(app.RobotAxes,'XData',linkpoints(1,:),'YData',a1+linkpoints(2,:),'Facecolor',[0.8 0 0]);
            app.robot.link1 = patch(app.RobotAxes,'XData',linkpoints(2,:),'YData',linkpoints(1,:),'FaceColor',[0.8 0 0]);
            app.robot.joint1 = rectangle(app.RobotAxes,'Position',[-hole/2 -hole/2 hole hole],'Curvature',[1 1],'FaceColor',[0.8 0.8 0.8]);
            app.robot.joint2 = rectangle(app.RobotAxes,'Position',[-hole/2 a1-hole/2 hole hole],'Curvature',[1 1],'FaceColor',[0.8 0.8 0.8]);
            app.robot.tool = rectangle(app.RobotAxes,'Position',[a1-hole/2 a1-hole/2 hole hole],'Curvature',[1 1],'FaceColor','b','EdgeColor','w');
            app.robot.path = line(app.RobotAxes,'XData',0.25,'YData',0,'Color','b');
            text(app.RobotAxes,'Position',[0.2 0.21],'String','Clock:');
            app.robot.time = text(app.RobotAxes,'Position',[0.24 0.21],'String','0.00');
            text(app.RobotAxes,'Position',[-0.05 -0.05],'String','Q','Color','w');
            text(app.RobotAxes,'Position',[-0.039 -0.0525],'String','UANSER','Color','w','FontSize',6);
            patch(app.RobotAxes,'XData',Uxpoints+0.04,'YData',Uypoints-0.055,'FaceColor',[0.8 0 0],'EdgeColor',[1 1 1]);
        end

        % Value changing function: ModelNameEditField
        function ModelNameEditFieldValueChanging(app, event)
            app.StartButton.Enable = 'off';
            app.LoadButton.Enable = 'on';
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            modelName = app.ModelNameEditField.Value;

            % Do some simple error checking on the input
            if ~(exist(modelName,'file')==4)
                estr = sprintf('The model %s.mdl cannot be found.',modelName);
                errordlg(estr,'Model not found error','modal');
                return
            end

            % Load the simulink model
            if ~modelIsLoaded(app,modelName)
                load_system(modelName);
            end

            % Create some application data storing various
            % pieces of information about the model's initial state.
            app.modelName = modelName;
            app.tuning.SpeedValue = get_param(sprintf('%s/Speed',app.modelName),'Value');
            app.tuning.StopTime = get_param(app.modelName,'Stoptime');
            app.tuning.SampleTime = get_param(app.modelName,'FixedStep');
            app.tuning.GraphicsRate = get_param(sprintf('%s/Phi',app.modelName),'SampleTime');

            switch (get_param(app.modelName,'SimulationMode'))
                case 'normal'
                    app.NormalButton.Value=1;
                case 'external'
                    app.ExternalButton.Value=1;
                    checkModelConfigurationParameters(app);
                otherwise
                    set_param(app.modelName,'SimulationMode','normal');
            end

            % The tuning parameters needs to be poked into the GUI
            app.SpeedEditField.Value=app.tuning.SpeedValue;
            app.StopTimeEditField.Value=app.tuning.StopTime;
            app.SampleTimeEditField.Value=app.tuning.SampleTime;
            app.GraphicsRateEditField.Value=app.tuning.GraphicsRate;

            % if model contains a wall stiffness, set GUI to display wall
            if (~isempty(find_system(app.modelName,'Name','WallStiffness')))
                app.DisplayWallCheckBox.Value=1;
                app.robot.wall.Visible='on';
                app.RobotEnvironmentPanel.Enable='on';
                app.tuning.WallStiffnessValue = get_param(sprintf('%s/WallStiffness',app.modelName),'Value');
                app.WallStiffnessEditField.Value=app.tuning.WallStiffnessValue;
            else
                app.RobotEnvironmentPanel.Enable='off';
                app.robot.wall.Visible='off';
            end

            % List the blocks that are to have listeners applied
            app.viewing = struct(...
                'blockName','',...
                'blockHandle',[],...
                'blockEvent','',...
                'blockFcn',[]);
            % Every block has a name
            app.viewing(1).blockName = sprintf('%s/Phi',app.modelName);

            % That block has a handle
            % (This will be used in the graphics drawing callback, and is done here
            % as it should speed things up rather than searching for the handle
            % during every event callback.)
            app.viewing(1).blockHandle = get_param(app.viewing(1).blockName,'Handle');

            % List the block event to be listened for
            app.viewing(1).blockEvent = 'PostOutputs';
            app.viewing(1).refreshRate = str2double(get_param(app.viewing(1).blockName,'SampleTime'));

            % List the function to be called
            % (These must be subfunctions within this mfile).
            app.viewing(1).blockFcn = @(block, event) jointAngleListener(app,block,event);

            app.StartButton.Enable = 'on';
            app.LoadButton.Enable = 'off';
            app.ModeButtonGroup.Enable = 'on';
            app.ParameterTuningPanel.Enable = 'on';
        end

        % Selection changed function: ModeButtonGroup
        function ModeButtonGroupSelectionChanged(app, event)
            if app.ExternalButton.Value == 1
                set_param(app.modelName,'SimulationMode','external');
                checkModelConfigurationParameters(app);
            else
                set_param(app.modelName,'SimulationMode','normal');
            end
        end

        % Value changed function: SpeedEditField
        function SpeedEditFieldValueChanged(app, event)
            str = app.SpeedEditField.Value;
            newValue = str2double(str);

            % Do the change if it's valid
            if ~isnan(newValue)
                % poke the new value into the model
                set_param(sprintf('%s/Speed',app.modelName),'Value',str);
                % store the new value
                app.tuning.SpeedValue = str;
            else
                % throw up an error dialog
                estr = sprintf('%s is an invalid Speed value.',str);
                errordlg(estr,'Speed Tuning Error','modal');
                % reset the edit box to the old value
                app.SpeedEditField.Value=app.tuning.SpeedValue;
            end
        end

        % Value changed function: StopTimeEditField
        function StopTimeEditFieldValueChanged(app, event)
            str = app.StopTimeEditField.Value;
            newValue = str2double(str);

            % Do the change if it's valid
            if (~isnan(newValue)&& (newValue>0))
                % poke the new value into the model
                set_param(app.modelName,'StopTime',str);
                % store the new value
                app.tuning.StopTime = str;
            else
                % throw up an error dialog
                estr = sprintf('%s is an invalid Stop Time Value.',str);
                errordlg(estr,'Stop Time Tuning Error','modal');
                % reset the edit box to the old value
                app.StopTimeEditField.Value=app.tuning.StopTime;
            end
        end

        % Value changed function: SampleTimeEditField
        function SampleTimeEditFieldValueChanged(app, event)
            str = app.SampleTimeEditField.Value;
            newValue = str2double(str);

            % Do the change if it's valid
            if (~isnan(newValue)&& (newValue>0))
                % poke the new value into the model
                set_param(app.modelName,'FixedStep',str);
                % store the new value
                app.tuning.SampleTime = str;
                if (app.ExternalButton.Value == 1 &&  newValue<0.001)
                    warndlg('The Fundamental Sampletime is too small. It should be at least 0.001 when using external mode.');
                end
            else
                % throw up an error dialog
                estr = sprintf('%s is an invalid Stop Time Value.',str);
                errordlg(estr,'Stop Time Tuning Error','modal');
                % reset the edit box to the old value
                app.SampleTimeEditField.Value=app.tuning.SampleTime;
            end
        end

        % Value changed function: GraphicsRateEditField
        function GraphicsRateEditFieldValueChanged(app, event)
            str = app.GraphicsRateEditField.Value;
            newValue = str2double(str);
            SampleTime = str2double(app.tuning.SampleTime);

            if (~isnan(newValue) && (newValue>0) && (rem(newValue,SampleTime)==0))
                % poke the new value into the model
                set_param(sprintf('%s/Phi',app.modelName),'SampleTime',str);
                % store the new value
                app.tuning.GraphicsRate = str;
            else
                % throw up an error dialog
                estr = sprintf('%s is an invalid Graphics Refresh rate. The Graphics Refresh rate must be a multiple of the fundamental sample time',str);
                errordlg(estr,'Graphics Refresh Rate Error','modal');
                % reset the edit box to the old value
                app.GraphicsRateEditField.Value=app.tuning.GraphicsRate;
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            set(app.robot.path,'XData',[],'YData',[]);

            % Load the model if required (it may have been closed manually).
            if ~modelIsLoaded(app,app.modelName)
                load_system(app.modelName);
            end

            % toggle the buttons
            app.StartButton.Enable='off';
            app.LoadButton.Enable='off';
            app.StopButton.Enable='on';

            % disable Mode changes
            app.ModeButtonGroup.Enable='off';
            app.ParameterTuningPanel.Enable='off';
            app.ModelNameEditField.Enable='off';

            % Push the current Speed value in the UI into the model
            SpeedEditFieldValueChanged(app);
            StopTimeEditFieldValueChanged(app);
            SampleTimeEditFieldValueChanged(app);
            GraphicsRateEditFieldValueChanged(app);

            % With Simulink versions 2019 and later, there is no longer a need to manually build the model
            % when in external mode. The start command will automatically trigger a build if required
            % before starting the model. Placing the event listener before building will cause
            % an error, so we have to add the event listener after starting the model
            switch (get_param(app.modelName,'SimulationMode'))
                case 'normal'
                    set_param(app.modelName,'SimulationCommand','start');
                case 'external'
                    qc_start_model(app.modelName);
                otherwise
                    % shouldn't be able to get in here
                    errordlg('Selection Error',...
                        'Neither simulation nor RT was attempted.', 'modal');
            end
            addEventListener(app);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            switch (get_param(app.modelName,'SimulationMode'))
                case 'normal'
                    set_param(app.modelName,'SimulationCommand','stop');
                case 'external'
                    qc_stop_model(app.modelName);
                otherwise
                    set_param(app.modelName,'SimulationCommand','stop');
            end
            stopSimulation(app);
        end

        % Button pushed function: RunScriptButton
        function RunScriptButtonPushed(app, event)
            script = app.ScriptNameEditField.Value;
            if exist(script,"file")
                evalin('base',script);
            else
                errordlg('The script cannot be found')
            end
        end

        % Value changed function: DisplayWallCheckBox
        function DisplayWallCheckBoxValueChanged(app, event)
            checked = app.DisplayWallCheckBox.Value;
            if (checked)
                app.robot.wall.Visible='on';
            else
                app.robot.wall.Visible='off';
            end
        end

        % Value changed function: WallStiffnessEditField
        function WallStiffnessEditFieldValueChanged(app, event)
            str = app.WallStiffnessEditField.Value;
            newValue = str2double(str);

            % Do the change if it's valid
            if (~isnan(newValue)&& (newValue>0))
                % poke the new value into the model
                set_param(sprintf('%s/WallStiffness',app.modelName),'Value',str);
                % store the new value
                app.tuning.WallStiffnessValue = str;
            else
                % throw up an error dialog
                estr = sprintf('%s is an invalid Wall Stiffness Value.',str);
                errordlg(estr,'Wall Stiffness Error','modal');
                % reset the edit box to the old value
                app.WallStiffnessEditField.Value=app.tuning.WallStiffnessValue;
            end
        end

        % Close request function: 
        % UIFigureforSimulatingControllingQuanser2DOFRobot
        function CloseRequest(app, event)
            % Can only close the UI if the model has been stopped
            % Can only stop the model is it hasn't already been unloaded (perhaps
            % manually).
            if modelIsLoaded(app,app.modelName)
                switch get_param(app.modelName,'SimulationStatus')
                    case 'stopped'
                        delete(app);
                    otherwise
                        errordlg('The model must be stopped before the UI is closed',...
                            'UI Close error','modal');
                end
            else
                delete(app);
            end
        end

        % Menu selected function: ApplicationHelpMenu
        function ApplicationHelpMenuSelected(app, event)
            str = sprintf('This app creates a simple user interface for running a Simulink model');
            str = strcat(str,' that simulates/controls a planar 2-DOF robot.');
            str = strcat(str,' This app is designed for use with the models developed in');
            str = strcat(str,' Intro to Robot Control (ME EN 5230/6230) at the University of Utah');
            str = strcat(str,' for simulating/controlling a Quanser 2-DOF serial robot arm.');
            str = strcat(str,' The app allows the model to be run in either simulation (normal mode)');
            str = strcat(str,' or as a real-time executable (external mode) using the QUARC software by Quanser.');
            str = strcat(str,' Note that with MATLAB/Simulink version 2019 and later, when starting a model in external mode,');
            str = strcat(str,' the model will automatically build if required before running');
            str = strcat(str,' External Mode only allows event listeners to be attached to Scope blocks.');
            str = strcat(str,' This app looks for a scope block named "Phi" in the model to attach the listener.');
            str = strcat(str,' "Phi" should represent the absolute joint angles.');
            str = strcat(str,' The sampletime of your scope block determines the refresh rate of the GUI.');
            str = strcat(str,' It is advisable to use a Rate Transition block in front of the Phi scope block,');
            str = strcat(str,' which allows you to set a slower sampletime on your scope than the rest of your model.');
            str = strcat(str,' Otherwise the app cannot refresh fast enough to keep up with the simulation.');
            str = strcat(str,' For example, set the fundamental sampletime to 0.002 and Graphics Refresh rate (Phi scope sampletime) to 0.02.');
            str = strcat(str,' The Data Analysis Panel allows you to execute a custom script of your choice in the main MATLAB workspace.');
            str = strcat(str,' You can use this feature to analyze/plot/save the data sent to your workspace from any "To Workspace" blocks in your model.');
            helpdlg(str)
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            helpdlg('Robot App Version 1.1 was Created by Prof. Stephen Mascaro, University of Utah')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigureforSimulatingControllingQuanser2DOFRobot = uifigure('Visible', 'off');
            app.UIFigureforSimulatingControllingQuanser2DOFRobot.Name = 'MATLAB App for Simulating/Controlling Quanser 2-DOF Robot';
            app.UIFigureforSimulatingControllingQuanser2DOFRobot.CloseRequestFcn = createCallbackFcn(app, @CloseRequest, true);
            app.HelpMenu = uimenu(app.UIFigureforSimulatingControllingQuanser2DOFRobot);
            app.HelpMenu.Text = 'Help';
            app.ApplicationHelpMenu = uimenu(app.HelpMenu);
            app.ApplicationHelpMenu.MenuSelectedFcn = createCallbackFcn(app, @ApplicationHelpMenuSelected, true);
            app.ApplicationHelpMenu.Text = 'Application Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.RobotAxes = uiaxes(app.UIFigureforSimulatingControllingQuanser2DOFRobot);
            title(app.RobotAxes, 'Robot Configuration')
            xlabel(app.RobotAxes, 'X')
            ylabel(app.RobotAxes, 'Y')
            zlabel(app.RobotAxes, 'Z')
            app.RobotAxes.XLim = [-0.075 0.3];
            app.RobotAxes.YLim = [-0.075 0.225];
            app.ModeButtonGroup = uibuttongroup(app.UIFigureforSimulatingControllingQuanser2DOFRobot);
            app.ModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModeButtonGroupSelectionChanged, true);
            app.ModeButtonGroup.Enable = 'off';
            app.ModeButtonGroup.Title = 'Mode';
            app.NormalButton = uiradiobutton(app.ModeButtonGroup);
            app.NormalButton.Text = 'Normal  (Simulation)';
            app.NormalButton.Value = true;
            app.ExternalButton = uiradiobutton(app.ModeButtonGroup);
            app.ExternalButton.Text = 'External (Real-Time)';
            app.ParameterTuningPanel = uipanel(app.UIFigureforSimulatingControllingQuanser2DOFRobot);
            app.ParameterTuningPanel.Enable = 'off';
            app.ParameterTuningPanel.Title = 'Parameter Tuning';
            app.TrajectorySpeedcirclessLabel = uilabel(app.ParameterTuningPanel);
            app.TrajectorySpeedcirclessLabel.Text = 'Trajectory Speed (circles/s)';
            app.SpeedEditField = uieditfield(app.ParameterTuningPanel, 'text');
            app.SpeedEditField.ValueChangedFcn = createCallbackFcn(app, @SpeedEditFieldValueChanged, true);
            app.StopTimesEditFieldLabel = uilabel(app.ParameterTuningPanel);
            app.StopTimesEditFieldLabel.Text = 'Stop Time (s)';
            app.StopTimeEditField = uieditfield(app.ParameterTuningPanel, 'text');
            app.StopTimeEditField.ValueChangedFcn = createCallbackFcn(app, @StopTimeEditFieldValueChanged, true);
            app.FundamentalSampleTimesLabel = uilabel(app.ParameterTuningPanel);
            app.FundamentalSampleTimesLabel.Text = 'Fundamental Sample Time (s)';
            app.SampleTimeEditField = uieditfield(app.ParameterTuningPanel, 'text');
            app.SampleTimeEditField.ValueChangedFcn = createCallbackFcn(app, @SampleTimeEditFieldValueChanged, true);
            app.GraphicsRefreshRatesLabel = uilabel(app.ParameterTuningPanel);
            app.GraphicsRefreshRatesLabel.Text = 'Graphics Refresh Rate (s)';
            app.GraphicsRateEditField = uieditfield(app.ParameterTuningPanel, 'text');
            app.GraphicsRateEditField.ValueChangedFcn = createCallbackFcn(app, @GraphicsRateEditFieldValueChanged, true);
            app.OperationsPanel = uipanel(app.UIFigureforSimulatingControllingQuanser2DOFRobot);
            app.OperationsPanel.Title = 'Operations';
            app.LoadButton = uibutton(app.OperationsPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Model';
            app.StartButton = uibutton(app.OperationsPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start Model';
            app.StopButton = uibutton(app.OperationsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop Model';
            app.DataAnalysisPanel = uipanel(app.UIFigureforSimulatingControllingQuanser2DOFRobot);
            app.DataAnalysisPanel.Title = 'Data Analysis';
            app.RunScriptButton = uibutton(app.DataAnalysisPanel, 'push');
            app.RunScriptButton.ButtonPushedFcn = createCallbackFcn(app, @RunScriptButtonPushed, true);
            app.RunScriptButton.Text = 'Run Script';
            app.ScriptNameEditFieldLabel = uilabel(app.DataAnalysisPanel);
            app.ScriptNameEditFieldLabel.Text = 'Script Name:';
            app.ScriptNameEditField = uieditfield(app.DataAnalysisPanel, 'text');
            app.RobotEnvironmentPanel = uipanel(app.UIFigureforSimulatingControllingQuanser2DOFRobot);
            app.RobotEnvironmentPanel.Enable = 'off';
            app.RobotEnvironmentPanel.Title = 'Robot Environment';
            app.DisplayWallCheckBox = uicheckbox(app.RobotEnvironmentPanel);
            app.DisplayWallCheckBox.ValueChangedFcn = createCallbackFcn(app, @DisplayWallCheckBoxValueChanged, true);
            app.DisplayWallCheckBox.Text = 'Display Wall';
            app.WallStiffnessNmmEditFieldLabel = uilabel(app.RobotEnvironmentPanel);
            app.WallStiffnessNmmEditFieldLabel.Text = 'Wall Stiffness (N/mm)';
            app.WallStiffnessEditField = uieditfield(app.RobotEnvironmentPanel, 'text');
            app.WallStiffnessEditField.ValueChangedFcn = createCallbackFcn(app, @WallStiffnessEditFieldValueChanged, true);
            app.ModelNameEditFieldLabel = uilabel(app.UIFigureforSimulatingControllingQuanser2DOFRobot);
            app.ModelNameEditFieldLabel.Text = 'Model Name:';
            app.ModelNameEditField = uieditfield(app.UIFigureforSimulatingControllingQuanser2DOFRobot, 'text');
            app.ModelNameEditField.ValueChangingFcn = createCallbackFcn(app, @ModelNameEditFieldValueChanging, true);
            % Show the figure after all components are created
            app.UIFigureforSimulatingControllingQuanser2DOFRobot.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigureforSimulatingControllingQuanser2DOFRobot)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigureforSimulatingControllingQuanser2DOFRobot)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigureforSimulatingControllingQuanser2DOFRobot)
        end
    end
end